classdef binAnalyser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        Waveform1previewPanel           matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        WaveformendpointEditField       matlab.ui.control.NumericEditField
        WaveformendpointEditFieldLabel  matlab.ui.control.Label
        WaveformstartpointEditField     matlab.ui.control.NumericEditField
        WaveformstartpointEditFieldLabel  matlab.ui.control.Label
        HigherfrequencykHzEditField     matlab.ui.control.NumericEditField
        HigherfrequencykHzEditFieldLabel  matlab.ui.control.Label
        LowerfrequencykHzEditField      matlab.ui.control.NumericEditField
        LowerfrequencykHzEditFieldLabel  matlab.ui.control.Label
        FilteringorderEditField         matlab.ui.control.NumericEditField
        FilteringorderEditFieldLabel    matlab.ui.control.Label
        FiltrationCheckBox              matlab.ui.control.CheckBox
        UIAxes2_1                       matlab.ui.control.UIAxes
        Waveform1bluePanel              matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        Label                           matlab.ui.control.Label
        FOSChDropDown                   matlab.ui.control.DropDown
        FOSChDropDownLabel              matlab.ui.control.Label
        PZTChDropDown                   matlab.ui.control.DropDown
        PZTChDropDownLabel              matlab.ui.control.Label
        Selectfile1Button               matlab.ui.control.Button
        CenterPanel                     matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        Panel                           matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        SelectFFTsourceDropDown         matlab.ui.control.DropDown
        SelectFFTsourceDropDownLabel    matlab.ui.control.Label
        EditField_2                     matlab.ui.control.NumericEditField
        EditField                       matlab.ui.control.NumericEditField
        FrequencylimitLabel             matlab.ui.control.Label
        CorrelationoftheFOSspectraequalsto0Label  matlab.ui.control.Label
        CorrelationofthePZTspectraequalsto0Label  matlab.ui.control.Label
        UpdateButton                    matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        Waveform2previewPanel           matlab.ui.container.Panel
        GridLayout10_2                  matlab.ui.container.GridLayout
        WaveformendpointEditField_2     matlab.ui.control.NumericEditField
        WaveformendpointEditField_2Label  matlab.ui.control.Label
        WaveformstartpointEditField_2   matlab.ui.control.NumericEditField
        WaveformstartpointEditField_2Label  matlab.ui.control.Label
        HigherfrequencykHzEditField_2   matlab.ui.control.NumericEditField
        HigherfrequencykHzEditField_2Label  matlab.ui.control.Label
        LowerfrequencykHzEditField_2    matlab.ui.control.NumericEditField
        LowerfrequencykHzEditField_2Label  matlab.ui.control.Label
        FilteringorderEditField_2       matlab.ui.control.NumericEditField
        FilteringorderEditField_2Label  matlab.ui.control.Label
        FiltrationCheckBox_2            matlab.ui.control.CheckBox
        UIAxes2_2                       matlab.ui.control.UIAxes
        Waveform2redPanel               matlab.ui.container.Panel
        GridLayout5_2                   matlab.ui.container.GridLayout
        Label_2                         matlab.ui.control.Label
        FOSChDropDown_2                 matlab.ui.control.DropDown
        FOSChDropDown_2Label            matlab.ui.control.Label
        PZTChDropDown_2                 matlab.ui.control.DropDown
        PZTChDropDown_2Label            matlab.ui.control.Label
        Selectfile2Button               matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        filepath_1
        filepath_2
        file_1
        file_2
        file_1_filtered
        file_2_filtered
        file_1_loaded
        file_2_loaded
        file_1_changed
        file_2_changed
        file_1_desc
        file_2_desc
        file_1_line
        file_2_line
        file_1_FFT
        file_1_FFT_filtered
    end
    
    methods (Access = private)
        
        function update_line_1(app)
            [~, buff] = importAgilentBin(app.filepath_1, app.PZTChDropDown.Value);
            if app.FiltrationCheckBox.Value
                buff = signal_filtering(buff, app.FilteringorderEditField.Value, app.LowerfrequencykHzEditField.Value, app.HigherfrequencykHzEditField.Value, 1/app.file_1_desc(1).xIncrement);
                plot(app.UIAxes2_1, buff(app.WaveformstartpointEditField.Value : app.WaveformendpointEditField.Value));
            else
                plot(app.UIAxes2_1, buff(app.WaveformstartpointEditField.Value : app.WaveformendpointEditField.Value));
                
            end
        end

        function update_line_2(app)
            [~, buff] = importAgilentBin(app.filepath_2, app.PZTChDropDown_2.Value);
            if app.FiltrationCheckBox_2.Value
                buff = signal_filtering(buff, app.FilteringorderEditField_2.Value, app.LowerfrequencykHzEditField_2.Value, app.HigherfrequencykHzEditField_2.Value, 1/app.file_2_desc(1).xIncrement);
                plot(app.UIAxes2_2, buff(app.WaveformstartpointEditField_2.Value : app.WaveformendpointEditField_2.Value));
            else
                plot(app.UIAxes2_2, buff(app.WaveformstartpointEditField_2.Value : app.WaveformendpointEditField_2.Value));
            end
        end

        function plot_FFT_1(app)
            buff_ch = 0;
            switch app.SelectFFTsourceDropDown.Value
                case 'FOS'
                    buff_ch = str2double(app.FOSChDropDown.Value);
                case 'PZT'
                    buff_ch = str2double(app.PZTChDropDown.Value);
            end
            [~, buff] = importAgilentBin(app.filepath_1, buff_ch);
            buff = buff(app.WaveformstartpointEditField.Value : app.WaveformendpointEditField.Value);
            buff = signal_filtering(buff, app.FilteringorderEditField.Value, app.LowerfrequencykHzEditField.Value, app.HigherfrequencykHzEditField.Value, 1/app.file_1_desc(buff_ch).xIncrement);
            buff = abs(fftshift(fft(buff)));
            N = length(buff);
            file_1_df = 1 / app.file_1_desc(1).xIncrement;
            ddf = file_1_df / N;
            f = -file_1_df/2:ddf:file_1_df/2-ddf;
            fplot = f(floor((N+1)/2):N) / 1000;
%             hold on;
            yyaxis(app.UIAxes, 'left');
            plot(app.UIAxes, fplot, buff(floor((N+1)/2):N), 'Color','blue');
%             plot(app.UIAxes, buff);
            xlim(app.UIAxes, [app.EditField.Value app.EditField_2.Value]);
%             hold off
        end
        function plot_FFT_2(app)
            buff_ch = 0;
            switch app.SelectFFTsourceDropDown.Value
                case 'FOS'
                    buff_ch = str2double(app.FOSChDropDown_2.Value);
                case 'PZT'
                    buff_ch = str2double(app.PZTChDropDown_2.Value);
            end
            [~, buff] = importAgilentBin(app.filepath_2, buff_ch);
            buff = buff(app.WaveformstartpointEditField_2.Value : app.WaveformendpointEditField_2.Value);
            buff = signal_filtering(buff, app.FilteringorderEditField_2.Value, app.LowerfrequencykHzEditField_2.Value, app.HigherfrequencykHzEditField_2.Value, 1/app.file_2_desc(buff_ch).xIncrement);
            buff = abs(fftshift(fft(buff)));
            N = length(buff);
            file_2_df = 1 / app.file_2_desc(1).xIncrement;
            ddf = file_2_df / N;
            f = -file_2_df/2:ddf:file_2_df/2-ddf;
            fplot = f(floor((N+1)/2):N) / 1000;
%             hold on;
            yyaxis(app.UIAxes, 'right');
            plot(app.UIAxes, fplot, buff(floor((N+1)/2):N), 'Color','red');
%             plot(app.UIAxes, buff);
            xlim(app.UIAxes, [app.EditField.Value app.EditField_2.Value]);
%             hold off
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Selectfile1Button
        function Selectfile1ButtonPushed(app, event)
            [a, b] = uigetfile('./*.bin', 'Select a binary file');
            app.filepath_1 =  strcat(b,a );
            app.Selectfile1Button.Text = app.filepath_1;
            app.file_1_desc = importAgilentBinDesc(app.filepath_1);
            [~, buff] = size(app.file_1_desc);
            app.Label.Text = strcat(num2str(buff), ' ch in the file');
            app.file_1_changed = true;
            app.Waveform1previewPanel.Enable = "on";
            app.WaveformendpointEditField.Value = app.file_1_desc(1).nPoints;
            update_line_1(app);
        end

        % Button pushed function: Selectfile2Button
        function Selectfile2ButtonPushed(app, event)
            [a, b] = uigetfile('./*.bin', 'Select a binary file');
            app.filepath_2 =  strcat(b,a );
            app.Selectfile2Button.Text = app.filepath_2;
            app.file_2_desc = importAgilentBinDesc(app.filepath_2);
            [~, buff] = size(app.file_2_desc);
            app.Label_2.Text = strcat(num2str(buff), ' ch in the file');
            app.file_2_changed = true;
            app.Waveform2previewPanel.Enable = "on";
            app.WaveformendpointEditField_2.Value = app.file_2_desc(1).nPoints;
            update_line_2(app);
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            cla(app.UIAxes);
            plot_FFT_1(app);
            plot_FFT_2(app);
            
            app.file_1_changed = false;
            app.file_2_changed = false;
        end

        % Value changed function: FilteringorderEditField
        function FilteringorderEditFieldValueChanged(app, event)
            value = app.FilteringorderEditField.Value;
            if value < 1 || ~isnumeric(value)
                app.FilteringorderEditField.Value = 10;
            end
            app.file_1_changed = true;
            update_line_1(app);
        end

        % Value changed function: LowerfrequencykHzEditField
        function LowerfrequencykHzEditFieldValueChanged(app, event)
            value = app.LowerfrequencykHzEditField.Value;
            if value < 1 || ~isnumeric(value) || value >= app.HigherfrequencykHzEditField.Value
                app.LowerfrequencykHzEditField.Value = 10;
            end
            app.file_1_changed = true;
            update_line_1(app);
        end

        % Value changed function: WaveformstartpointEditField
        function WaveformstartpointEditFieldValueChanged(app, event)
            value = app.WaveformstartpointEditField.Value;
            if value < 1 || value >= app.WaveformendpointEditField.Value || value >= app.file_1_desc(1).nPoints
                app.WaveformstartpointEditField.Value = 1;
            end
            app.file_1_changed = true;
            update_line_1(app);
        end

        % Value changed function: HigherfrequencykHzEditField
        function HigherfrequencykHzEditFieldValueChanged(app, event)
            value = app.HigherfrequencykHzEditField.Value;
            if value <= app.LowerfrequencykHzEditField.Value || ~isnumeric(value) || value >= 1000/(4*app.file_1_desc(1).xIncrement)
                app.HigherfrequencykHzEditField.Value = 450;
            end
            app.file_1_changed = true;
            update_line_1(app);
        end

        % Value changed function: WaveformendpointEditField
        function WaveformendpointEditFieldValueChanged(app, event)
            value = app.WaveformendpointEditField.Value;
            if value < 1 || value <= app.WaveformstartpointEditField.Value || value >= app.file_1_desc(1).nPoints
                app.WaveformendpointEditField.Value = app.file_1_desc(1).nPoints;
            end
            app.file_1_changed = true;
            update_line_1(app);
        end

        % Value changed function: FilteringorderEditField_2
        function FilteringorderEditField_2ValueChanged(app, event)
            value = app.FilteringorderEditField_2.Value;
            if value < 1 || ~isnumeric(value)
                app.FilteringorderEditField_2.Value = 10;
            end
            app.file_1_changed = true;
            update_line_2(app);
        end

        % Value changed function: LowerfrequencykHzEditField_2
        function LowerfrequencykHzEditField_2ValueChanged(app, event)
            value = app.LowerfrequencykHzEditField_2.Value;
            if value < 1 || ~isnumeric(value) || value >= app.HigherfrequencykHzEditField_2.Value
                app.LowerfrequencykHzEditField_2.Value = 10;
            end
            app.file_2_changed = true;
            update_line_2(app);
        end

        % Value changed function: HigherfrequencykHzEditField_2
        function HigherfrequencykHzEditField_2ValueChanged(app, event)
            value = app.HigherfrequencykHzEditField_2.Value;
            if value <= app.LowerfrequencykHzEditField_2.Value || ~isnumeric(value) || value >= 1000/(4*app.file_2_desc(1).xIncrement)
                app.HigherfrequencykHzEditField_2.Value = 450;
            end
            app.file_2_changed = true;
            update_line_2(app);
        end

        % Value changed function: WaveformstartpointEditField_2
        function WaveformstartpointEditField_2ValueChanged(app, event)
            value = app.WaveformstartpointEditField_2.Value;
            if value < 1 || value >= app.WaveformendpointEditField_2.Value || value >= app.file_2_desc(1).nPoints
                app.WaveformstartpointEditField_2.Value = 1;
            end
            app.file_2_changed = true;
            update_line_2(app);
        end

        % Value changed function: WaveformendpointEditField_2
        function WaveformendpointEditField_2ValueChanged(app, event)
            value = app.WaveformendpointEditField_2.Value;
            if value < 1 || value <= app.WaveformstartpointEditField_2.Value || value >= app.file_2_desc(1).nPoints
                app.WaveformendpointEditField_2.Value = app.file_2_desc(1).nPoints;
            end
            app.file_2_changed = true;
            update_line_2(app);
        end

        % Value changed function: FiltrationCheckBox_2
        function FiltrationCheckBox_2ValueChanged(app, event)
            app.file_2_changed = true;
            update_line_2(app);
        end

        % Value changed function: FiltrationCheckBox
        function FiltrationCheckBoxValueChanged(app, event)
            app.file_1_changed = true;
            update_line_1(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {563, 563, 563};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {563, 563};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {233, '1x', 231};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.Waveform1bluePanel = uipanel(app.GridLayout2);
            app.Waveform1bluePanel.Title = 'Waveform 1 (blue)';
            app.GridLayout5 = uigridlayout(app.Waveform1bluePanel);
            app.Selectfile1Button = uibutton(app.GridLayout5, 'push');
            app.Selectfile1Button.ButtonPushedFcn = createCallbackFcn(app, @Selectfile1ButtonPushed, true);
            app.Selectfile1Button.Text = 'Select file 1';
            app.PZTChDropDownLabel = uilabel(app.GridLayout5);
            app.PZTChDropDownLabel.Text = 'PZT Ch';
            app.PZTChDropDown = uidropdown(app.GridLayout5);
            app.PZTChDropDown.Items = {'1', '2', '3'};
            app.PZTChDropDown.Value = '3';
            app.FOSChDropDownLabel = uilabel(app.GridLayout5);
            app.FOSChDropDownLabel.Text = 'FOS Ch';
            app.FOSChDropDown = uidropdown(app.GridLayout5);
            app.FOSChDropDown.Items = {'1', '2', '3'};
            app.FOSChDropDown.Value = '1';
            app.Label = uilabel(app.GridLayout5);
            app.Label.Text = '';
            app.Waveform1previewPanel = uipanel(app.GridLayout2);
            app.Waveform1previewPanel.Enable = 'off';
            app.Waveform1previewPanel.Title = 'Waveform 1 preview';
            app.GridLayout10 = uigridlayout(app.Waveform1previewPanel);
            app.UIAxes2_1 = uiaxes(app.GridLayout10);
            title(app.UIAxes2_1, 'Waveform 1')
            zlabel(app.UIAxes2_1, 'Z')
            app.FiltrationCheckBox = uicheckbox(app.GridLayout10);
            app.FiltrationCheckBox.ValueChangedFcn = createCallbackFcn(app, @FiltrationCheckBoxValueChanged, true);
            app.FiltrationCheckBox.Text = 'Filtration';
            app.FiltrationCheckBox.Value = true;
            app.FilteringorderEditFieldLabel = uilabel(app.GridLayout10);
            app.FilteringorderEditFieldLabel.Text = 'Filtering order';
            app.FilteringorderEditField = uieditfield(app.GridLayout10, 'numeric');
            app.FilteringorderEditField.ValueChangedFcn = createCallbackFcn(app, @FilteringorderEditFieldValueChanged, true);
            app.FilteringorderEditField.Value = 10;
            app.LowerfrequencykHzEditFieldLabel = uilabel(app.GridLayout10);
            app.LowerfrequencykHzEditFieldLabel.Text = 'Lower frequency, kHz';
            app.LowerfrequencykHzEditField = uieditfield(app.GridLayout10, 'numeric');
            app.LowerfrequencykHzEditField.ValueChangedFcn = createCallbackFcn(app, @LowerfrequencykHzEditFieldValueChanged, true);
            app.LowerfrequencykHzEditField.Value = 10;
            app.HigherfrequencykHzEditFieldLabel = uilabel(app.GridLayout10);
            app.HigherfrequencykHzEditFieldLabel.Text = 'Higher frequency, kHz';
            app.HigherfrequencykHzEditField = uieditfield(app.GridLayout10, 'numeric');
            app.HigherfrequencykHzEditField.ValueChangedFcn = createCallbackFcn(app, @HigherfrequencykHzEditFieldValueChanged, true);
            app.HigherfrequencykHzEditField.Value = 450;
            app.WaveformstartpointEditFieldLabel = uilabel(app.GridLayout10);
            app.WaveformstartpointEditFieldLabel.Text = 'Waveform start point';
            app.WaveformstartpointEditField = uieditfield(app.GridLayout10, 'numeric');
            app.WaveformstartpointEditField.ValueChangedFcn = createCallbackFcn(app, @WaveformstartpointEditFieldValueChanged, true);
            app.WaveformstartpointEditField.Value = 1;
            app.WaveformendpointEditFieldLabel = uilabel(app.GridLayout10);
            app.WaveformendpointEditFieldLabel.Text = 'Waveform end point';
            app.WaveformendpointEditField = uieditfield(app.GridLayout10, 'numeric');
            app.WaveformendpointEditField.ValueChangedFcn = createCallbackFcn(app, @WaveformendpointEditFieldValueChanged, true);
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.CenterPanel);
            app.UIAxes = uiaxes(app.GridLayout3);
            title(app.UIAxes, 'FFT spectra')
            xlabel(app.UIAxes, 'Frequency, kHz')
            ylabel(app.UIAxes, 'Magnitude')
            zlabel(app.UIAxes, 'Z')
            app.UpdateButton = uibutton(app.GridLayout3, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update!';
            app.CorrelationofthePZTspectraequalsto0Label = uilabel(app.GridLayout3);
            app.CorrelationofthePZTspectraequalsto0Label.Text = 'Correlation of the PZT spectra equals to 0';
            app.CorrelationoftheFOSspectraequalsto0Label = uilabel(app.GridLayout3);
            app.CorrelationoftheFOSspectraequalsto0Label.Text = 'Correlation of the FOS spectra equals to 0';
            app.Panel = uipanel(app.GridLayout3);
            app.Panel.Title = 'Panel';
            app.GridLayout9 = uigridlayout(app.Panel);
            app.FrequencylimitLabel = uilabel(app.GridLayout9);
            app.FrequencylimitLabel.Text = 'Frequency limit';
            app.EditField = uieditfield(app.GridLayout9, 'numeric');
            app.EditField_2 = uieditfield(app.GridLayout9, 'numeric');
            app.EditField_2.Value = 450;
            app.SelectFFTsourceDropDownLabel = uilabel(app.GridLayout9);
            app.SelectFFTsourceDropDownLabel.Text = 'Select FFT source';
            app.SelectFFTsourceDropDown = uidropdown(app.GridLayout9);
            app.SelectFFTsourceDropDown.Items = {'PZT', 'FOS'};
            app.SelectFFTsourceDropDown.Value = 'FOS';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout8 = uigridlayout(app.RightPanel);
            app.Waveform2redPanel = uipanel(app.GridLayout8);
            app.Waveform2redPanel.Title = 'Waveform 2 (red)';
            app.GridLayout5_2 = uigridlayout(app.Waveform2redPanel);
            app.Selectfile2Button = uibutton(app.GridLayout5_2, 'push');
            app.Selectfile2Button.ButtonPushedFcn = createCallbackFcn(app, @Selectfile2ButtonPushed, true);
            app.Selectfile2Button.Text = 'Select file 2';
            app.PZTChDropDown_2Label = uilabel(app.GridLayout5_2);
            app.PZTChDropDown_2Label.Text = 'PZT Ch';
            app.PZTChDropDown_2 = uidropdown(app.GridLayout5_2);
            app.PZTChDropDown_2.Items = {'1', '2', '3'};
            app.PZTChDropDown_2.Value = '3';
            app.FOSChDropDown_2Label = uilabel(app.GridLayout5_2);
            app.FOSChDropDown_2Label.Text = 'FOS Ch';
            app.FOSChDropDown_2 = uidropdown(app.GridLayout5_2);
            app.FOSChDropDown_2.Items = {'1', '2', '3'};
            app.FOSChDropDown_2.Value = '1';
            app.Label_2 = uilabel(app.GridLayout5_2);
            app.Label_2.Text = '';
            app.Waveform2previewPanel = uipanel(app.GridLayout8);
            app.Waveform2previewPanel.Enable = 'off';
            app.Waveform2previewPanel.Title = 'Waveform 2 preview';
            app.GridLayout10_2 = uigridlayout(app.Waveform2previewPanel);
            app.UIAxes2_2 = uiaxes(app.GridLayout10_2);
            title(app.UIAxes2_2, 'Waveform 1')
            zlabel(app.UIAxes2_2, 'Z')
            app.FiltrationCheckBox_2 = uicheckbox(app.GridLayout10_2);
            app.FiltrationCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @FiltrationCheckBox_2ValueChanged, true);
            app.FiltrationCheckBox_2.Text = 'Filtration';
            app.FiltrationCheckBox_2.Value = true;
            app.FilteringorderEditField_2Label = uilabel(app.GridLayout10_2);
            app.FilteringorderEditField_2Label.Text = 'Filtering order';
            app.FilteringorderEditField_2 = uieditfield(app.GridLayout10_2, 'numeric');
            app.FilteringorderEditField_2.ValueChangedFcn = createCallbackFcn(app, @FilteringorderEditField_2ValueChanged, true);
            app.FilteringorderEditField_2.Value = 10;
            app.LowerfrequencykHzEditField_2Label = uilabel(app.GridLayout10_2);
            app.LowerfrequencykHzEditField_2Label.Text = 'Lower frequency, kHz';
            app.LowerfrequencykHzEditField_2 = uieditfield(app.GridLayout10_2, 'numeric');
            app.LowerfrequencykHzEditField_2.ValueChangedFcn = createCallbackFcn(app, @LowerfrequencykHzEditField_2ValueChanged, true);
            app.LowerfrequencykHzEditField_2.Value = 10;
            app.HigherfrequencykHzEditField_2Label = uilabel(app.GridLayout10_2);
            app.HigherfrequencykHzEditField_2Label.Text = 'Higher frequency, kHz';
            app.HigherfrequencykHzEditField_2 = uieditfield(app.GridLayout10_2, 'numeric');
            app.HigherfrequencykHzEditField_2.ValueChangedFcn = createCallbackFcn(app, @HigherfrequencykHzEditField_2ValueChanged, true);
            app.HigherfrequencykHzEditField_2.Value = 450;
            app.WaveformstartpointEditField_2Label = uilabel(app.GridLayout10_2);
            app.WaveformstartpointEditField_2Label.Text = 'Waveform start point';
            app.WaveformstartpointEditField_2 = uieditfield(app.GridLayout10_2, 'numeric');
            app.WaveformstartpointEditField_2.ValueChangedFcn = createCallbackFcn(app, @WaveformstartpointEditField_2ValueChanged, true);
            app.WaveformstartpointEditField_2.Value = 1;
            app.WaveformendpointEditField_2Label = uilabel(app.GridLayout10_2);
            app.WaveformendpointEditField_2Label.Text = 'Waveform end point';
            app.WaveformendpointEditField_2 = uieditfield(app.GridLayout10_2, 'numeric');
            app.WaveformendpointEditField_2.ValueChangedFcn = createCallbackFcn(app, @WaveformendpointEditField_2ValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = binAnalyser

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end